classdef WBA_Fixation_Analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ZihangXiaoLabel                matlab.ui.control.Label
        X_ConversionEditField          matlab.ui.control.NumericEditField
        X_ConversionEditFieldLabel     matlab.ui.control.Label
        LoadDataButton                 matlab.ui.control.Button
        TripplePlotButton              matlab.ui.control.Button
        Rows3NEditField                matlab.ui.control.NumericEditField
        Rows3NEditFieldLabel           matlab.ui.control.Label
        TotalVectorStrengthPlotButton  matlab.ui.control.Button
        BasicFixationPlotsButton       matlab.ui.control.Button
        WBA_Fixation_AnalysisLabel     matlab.ui.control.Label
    end

    
    properties (Access = public)
        res % Description
        x_value % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BasicFixationPlotsButton
        function BasicFixationPlotsButtonPushed(app, event)
    res=app.res;
    rows=app.Rows3NEditField.Value;
    frame_num=size(res,1);
    trial_num = size(res,2)
    cols=trial_num/rows;
    i=1;
while i<trial_num+1 %edit here
    newres=res(:,i);
    i=i+1;
    n=0;
    newres=newres-app.x_value/9.6;
    newres=newres*36;
    dashedline1=zeros(3600,1);
    dashedline2=zeros(3600,1);
    dashedline3=zeros(3600,1);
    dashedline2=dashedline2+180;
    dashedline3=dashedline3+360;
    while n<frame_num-1%number of frame-1
        n=n+1;
        first=newres(n,1);
        second=newres(n+1,1);
        detectionvalue=abs(first-second);
            if detectionvalue>=300
                newres(n,1)=nan;
                newres(n+1,1)=nan;
            end
 end
    subplot(rows,cols,i-1);%edit here
    graphB=plot(newres,'k');
    graphB.LineWidth = 0.5;
    hold on
    rectanglelines=plot(dashedline1,'--r');
    circlelines=plot(dashedline2,'--r');
    lines=plot(dashedline3,'--r');
 end
        end

        % Value changed function: Rows3NEditField
        function Rows3NEditFieldValueChanged(app, event)
            value = app.Rows3NEditField.Value;
            
        end

        % Button pushed function: TotalVectorStrengthPlotButton
        function TotalVectorStrengthPlotButtonPushed(app, event)
    res=app.res;
    trial_num = size(res,2)
            i=1;
angleaverage1=0;
angleaverage2=0;
while i<trial_num+1%enter the number of trials you want to analyse

    z=res(:,i);%copy all data from res
    i=i+1;
    z=z-app.x_value/9.6;% 
    z(z<0)=z(z<0)+10;
    z(z>10)=z(z>10)-10;
    z=z*36;
    meanz=mean(z);
    x=cos((z/360)*2*pi);
    y=sin((z/360)*2*pi);
    t1=mean(x);
    t2=mean(y);
    a=t1;
    b=t2;
    angle= atan(t2/t1);

    title('WTB')%change the title of the graph
    hold on
    c=plot([0 a],[0 b],'-k');
    cosa=cos(angle);
    sina=sin(angle);
    if a<0
        p=plot(-cosa*1.02,-sina*1.02,'.k');
        p.MarkerSize = 20;
        set(gca,'XColor', 'none','YColor','none')
        axis equal


    else
        p=plot(cosa*1.02,sina*1.02,'.k');
        p.MarkerSize = 20;
        set(gca,'XColor', 'none','YColor','none')%remove the axis
        axis equal

    end
    th = 0:pi/50:2*pi;
    xunit = 1 * cos(th) + 0;
    yunit = 1 * sin(th) + 0;
    h = plot(xunit, yunit,'-k');
    h.MarkerEdgeColor = [1 0.5 0];
    set(gca,'XColor', 'none','YColor','none')
    axis equal
    angleaverage1=a+angleaverage1;
    angleaverage2=b+angleaverage2;
end
averageplot=plot([angleaverage1/11,0],[angleaverage2/11,0],'-r');
averageplot.LineWidth =2;
        end

        % Button pushed function: TripplePlotButton
        function TripplePlotButtonPushed(app, event)
    res=app.res;
            rows=app.Rows3NEditField.Value;
    frame_num=size(res,1);
    trial_num = size(res,2)
    cols=trial_num/rows;

i=0;
l=0;
% Enter rows&columns=number of trialsx3 here
cols=trial_num*3/rows;
graph_num=rows*cols;
while l<graph_num %enter the number of trials X3 you want to analysis
    i=i+1;
    n=0;
    l=l+1; 
    newres=res(:,i)-app.x_value/9.6;
    newres=newres*36;
    dashedline1=zeros(3600,1);
    dashedline2=zeros(3600,1);
    dashedline3=zeros(3600,1);
    dashedline2=dashedline2+180;
    dashedline3=dashedline3+360;
    subplot(rows,cols,l);
    polarhistogram(newres/360*(2*pi));
    hold on
    while n<3599%number of frame-1
        n=n+1;
        first=newres(n,1);
        second=newres(n+1,1);
        detectionvalue=abs(first-second);
            if detectionvalue>=300
                newres(n,1)=nan;
                newres(n+1,1)=nan;
            end
    end
    l=l+1;
    subplot(rows,cols,l); 
    graphB=plot(newres,'k');
    graphB.LineWidth = 0.5;
    hold on
    rectanglelines=plot(dashedline1,'--r');
    circlelines=plot(dashedline2,'--r');
    lines=plot(dashedline3,'--r');
    l=l+1;
    subplot(rows,cols,l)
    z=res(:,i);
    z=z-app.x_value/9.6;
    z(z<0)=z(z<0)+10;
    z(z>10)=z(z>10)-10;
    z=z*36;
    meanz=mean(z);
    x=cos((z/360)*2*pi);
    y=sin((z/360)*2*pi);
    t1=mean(x);
    t2=mean(y);
    a=t1;
    b=t2;
    angle= atan(t2/t1);
    hold on
    c=plot([0 a],[0 b],'-k');
    cosa=cos(angle);
    sina=sin(angle);
    if a<0
        p=plot(-cosa*1.02,-sina*1.02,'.k');
        p.MarkerSize = 10;
        set(gca,'XColor', 'none','YColor','none')
        axis equal


    else
        p=plot(cosa*1.02,sina*1.02,'.k');
        p.MarkerSize = 10;
        set(gca,'XColor', 'none','YColor','none')%remove the axis
        axis equal

    end
    th = 0:pi/50:2*pi;
    xunit = 1 * cos(th) + 0;
    yunit = 1 * sin(th) + 0;
    h = plot(xunit, yunit,'-k');
    h.MarkerEdgeColor = [1 0.5 0];
    set(gca,'XColor', 'none','YColor','none')
    axis equal
  
end
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
[file,path] = uigetfile;
addpath(path);
res1 = importdata(file);
app.res=res1;
        end

        % Value changed function: X_ConversionEditField
        function X_ConversionEditFieldValueChanged(app, event)
            app.x_value = app.X_ConversionEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WBA_Fixation_AnalysisLabel = uilabel(app.UIFigure);
            app.WBA_Fixation_AnalysisLabel.Text = 'WBA_Fixation_Analysis ';
            app.BasicFixationPlotsButton = uibutton(app.UIFigure, 'push');
            app.BasicFixationPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @BasicFixationPlotsButtonPushed, true);
            app.BasicFixationPlotsButton.Text = 'Basic Fixation Plots';
            app.TotalVectorStrengthPlotButton = uibutton(app.UIFigure, 'push');
            app.TotalVectorStrengthPlotButton.ButtonPushedFcn = createCallbackFcn(app, @TotalVectorStrengthPlotButtonPushed, true);
            app.TotalVectorStrengthPlotButton.Text = 'Total Vector Strength Plot';
            app.Rows3NEditFieldLabel = uilabel(app.UIFigure);
            app.Rows3NEditFieldLabel.Text = 'Rows(3N)';
            app.Rows3NEditField = uieditfield(app.UIFigure, 'numeric');
            app.Rows3NEditField.ValueChangedFcn = createCallbackFcn(app, @Rows3NEditFieldValueChanged, true);
            app.TripplePlotButton = uibutton(app.UIFigure, 'push');
            app.TripplePlotButton.ButtonPushedFcn = createCallbackFcn(app, @TripplePlotButtonPushed, true);
            app.TripplePlotButton.Text = 'Tripple Plot';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.X_ConversionEditFieldLabel = uilabel(app.UIFigure);
            app.X_ConversionEditFieldLabel.Text = 'X_Conversion';
            app.X_ConversionEditField = uieditfield(app.UIFigure, 'numeric');
            app.X_ConversionEditField.ValueChangedFcn = createCallbackFcn(app, @X_ConversionEditFieldValueChanged, true);
            app.ZihangXiaoLabel = uilabel(app.UIFigure);
            app.ZihangXiaoLabel.Text = ' Zihang Xiao';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WBA_Fixation_Analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end